classdef scriptmaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        dtLabel               matlab.ui.control.Label
        dt                    matlab.ui.control.NumericEditField
        ShellScript           matlab.ui.control.TextArea
        GenerateScriptButton  matlab.ui.control.Button
        Clear                 matlab.ui.control.Button
        BrowseUG4             matlab.ui.control.Button
        Label_5               matlab.ui.control.Label
        UG4exe                matlab.ui.control.EditField
        endtimeLabel          matlab.ui.control.Label
        endtime               matlab.ui.control.NumericEditField
        SaveScriptButton      matlab.ui.control.Button
        RunScript             matlab.ui.control.Button
    end

    
    properties (Access = private)
        scriptmakerpath ; % this is for the fullpath of the scriptmaker file
        model1folderpath; % this is for the model1folderpath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateScriptButton
        function GenerateScript(app, event)
            app.scriptmakerpath = fileparts(which('scriptmaker.mlapp')); % this is the full path of the scriptmaker app
            fsepindex = regexp(app.scriptmakerpath,filesep);  % this extracts all the indices of the filesep '\' or '/' 
            app.model1folderpath = app.scriptmakerpath(1:fsepindex(end-1)-1); % this gets the path for the Model_1 folder without filesep
            
            % this constructs the path for the lua script
            luapath = fullfile(app.model1folderpath,'Model_Codes','Calcium_Codes','vdccFullCellCalcium.lua');
            
            % this constructs the voltage data path
            vpath = fullfile(app.model1folderpath,'Model_Results','Calcium_Simulation_Data','Converted_Voltage_Traces');
                
            % this constructs the output data path
            opath = fullfile(app.model1folderpath,'Model_Results','Calcium_Simulation_Data','Calcium_Simulation_Results');
            
            fig = app.UIFigure;
            
            % this constructs the geometry .swc path
            geometryList = dir(fullfile(app.model1folderpath,'Model_Results','Calcium_Simulation_Data', '*.swc'));
            
            % this does some file checks
            if (isempty(geometryList) == 1 || isempty(app.dt.Value)==1 || isempty(app.endtime) == 1)         
                % this checks of the geometry file is missing or the dt,
                % endtime are incorrect or empty
                message = sprintf('Missing the following:\n -ugshell binary path, or \n -geometry .swc file, or \n -dt time step size, or \n -endtime not specified.');
                uialert(fig,message,'Invalid Input');
            elseif (app.UG4exe.Value=="Browse for the UG4 executable" || isempty(app.UG4exe.Value)==1)          
                % this checks of the ugshell field is entered
                message = sprintf("Invalid UG4 path!");
                uialert(fig,message,'Invalid UG4 path');
            elseif (isempty(dir(fullfile(vpath, '*.dat')))==1)
                % this checks if the voltage data is available
                message = sprintf('Missing the Voltage Data.');
                uialert(fig,message,'Missing Voltage Data');
            else
                gridSWC = fullfile(geometryList(1).folder,geometryList(1).name);
                
                % this part concatenates all the strings that are saved to
                % the output box, this is the actual script commands for
                % running the UG4 simulation
                scriptText='';
                scriptText=strcat(scriptText, app.UG4exe.Value);
                scriptText=strcat(scriptText," -ex ");
                scriptText=strcat(scriptText, luapath);
                scriptText=strcat(scriptText," -grid ");
                scriptText=strcat(scriptText, gridSWC);
                scriptText=strcat(scriptText," -numRefs 0 -setting none ");
                scriptText=strcat(scriptText,sprintf(" -dt %0.7f",app.dt.Value));
                scriptText=strcat(scriptText,sprintf(" -endTime %0.7f",app.endtime.Value));
                scriptText=strcat(scriptText," -vtk -pstep 1.0e-5 -vmData ");
                scriptText=strcat(scriptText,vpath);
                scriptText=strcat(scriptText," -outName ");
                scriptText=strcat(scriptText,opath);
                scriptText=strcat(scriptText," -solver GS -minDef 1e-11 -numNewton 5 -vSampleRate 0.025 ");
                scriptText=strrep(scriptText,"\","/");
                app.ShellScript.Value=scriptText;
            end 
        end

        % Button pushed function: Clear
        function ClearScript(app, event)
            app.ShellScript.Value='';
        end

        % Button pushed function: BrowseUG4
        function SearchUG4Folder(app, event)
            [file,path] = uigetfile;
            if (path == 0)
                app.UG4exe.Value= 'Browse for the UG4 executable';
            else
                app.UIFigure.Visible='off';
                app.UIFigure.Visible='on';
                app.UG4exe.Value=fullfile(path,file);
            end
        end

        % Button pushed function: SaveScriptButton
        function SaveScript(app, event)
            % this makes the shell script file name
            outfilename = strcat('calciumshellscript-',datestr(now,'YY-mm-DD-HH-MM-SS'));
            outfilename = strcat(outfilename,".sh");  % adds .sh file name type
            
            % this makes the full file path to the shell script
            filename = fullfile(app.model1folderpath,'Model_Results','Calcium_Simulation_Data',outfilename);
            fileID = fopen(filename,'w');
            
            % this write what is inside the text box to the shell script
            % file defined above
            my_str = string(app.ShellScript.Value);
            fprintf(fileID,my_str);
            fclose(fileID);
        end

        % Button pushed function: RunScript
        function RunShellScript(app, event)
            % this makes the shell script file name
            outfilename = strcat('calciumshellscript-',datestr(now,'YY-mm-DD-HH-MM-SS'));
            outfilename = strcat(outfilename,".sh");
            
            % this makes the full file path to the shell script
            filename = fullfile(app.model1folderpath,'Model_Results','Calcium_Simulation_Data',outfilename);
            fileID = fopen(filename,'w');
            
            % this write what is inside the text box to the shell script
            % file defined above
            my_str = string(app.ShellScript.Value);
            fprintf(fileID,my_str);
            fclose(fileID);
            
            % this executes the system call by running the shell script
            % using the sh command
            system(sprintf('sh %s',filename));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.dtLabel = uilabel(app.UIFigure);
            app.dtLabel.Text = 'dt = ';
            app.dt = uieditfield(app.UIFigure, 'numeric');
            app.dt.Value = 0.002;
            app.ShellScript = uitextarea(app.UIFigure);
            app.GenerateScriptButton = uibutton(app.UIFigure, 'push');
            app.GenerateScriptButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateScript, true);
            app.GenerateScriptButton.Text = 'Generate Script';
            app.Clear = uibutton(app.UIFigure, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearScript, true);
            app.Clear.Text = 'Clear';
            app.BrowseUG4 = uibutton(app.UIFigure, 'push');
            app.BrowseUG4.ButtonPushedFcn = createCallbackFcn(app, @SearchUG4Folder, true);
            app.BrowseUG4.Text = 'Browse';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.UG4exe = uieditfield(app.UIFigure, 'text');
            app.UG4exe.Value = 'Browse for the UG4 executable';
            app.endtimeLabel = uilabel(app.UIFigure);
            app.endtimeLabel.Text = 'end time = ';
            app.endtime = uieditfield(app.UIFigure, 'numeric');
            app.endtime.Value = 0.006;
            app.SaveScriptButton = uibutton(app.UIFigure, 'push');
            app.SaveScriptButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScript, true);
            app.SaveScriptButton.Text = 'Save Script';
            app.RunScript = uibutton(app.UIFigure, 'push');
            app.RunScript.ButtonPushedFcn = createCallbackFcn(app, @RunShellScript, true);
            app.RunScript.Text = 'Run Script';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scriptmaker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end