classdef scriptmaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BrowseLua                   matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        LuaFile                     matlab.ui.control.EditField
        BrowseGrid                  matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        GridFile                    matlab.ui.control.EditField
        BrowseOutFolder             matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        OutFolder                   matlab.ui.control.EditField
        BrowseVoltageFolder         matlab.ui.control.Button
        Label_4                     matlab.ui.control.Label
        VoltageFolder               matlab.ui.control.EditField
        dtLabel                     matlab.ui.control.Label
        dt                          matlab.ui.control.NumericEditField
        ShellScript                 matlab.ui.control.TextArea
        GenerateScriptButton        matlab.ui.control.Button
        Clear                       matlab.ui.control.Button
        BrowseUG4                   matlab.ui.control.Button
        Label_5                     matlab.ui.control.Label
        UG4exe                      matlab.ui.control.EditField
        endtimeLabel                matlab.ui.control.Label
        endtime                     matlab.ui.control.NumericEditField
        SaveScriptButton            matlab.ui.control.Button
        BrowseShellScriptOutFolder  matlab.ui.control.Button
        ShellScriptFolder           matlab.ui.control.EditField
        RunScript                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseLua
        function SearchLuaScript(app, event)
            [file,path]=uigetfile('*.lua');                    % Calls 'uigetdir' to obtain the directory location from the user
            if (path == 0)
                app.LuaFile.Value = 'Browse for the .lua script';
            else
                app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
                app.UIFigure.Visible = 'on';      
                app.LuaFile.Value = fullfile(path,file);  % Sets the label text to be the selected path
            end
        end

        % Button pushed function: BrowseGrid
        function SearchGridFile(app, event)
            [file,path]=uigetfile('*.swc');
            if (path == 0)
                app.GridFile.Value = 'Browse for the .swc grid file';
            else
                app.UIFigure.Visible='off';
                app.UIFigure.Visible='on';
                app.GridFile.Value=fullfile(path,file);
            end
        end

        % Button pushed function: BrowseOutFolder
        function SearchOutFolder(app, event)
            path = uigetdir;
            if (path == 0)
                path = 'Use Browse to set the output folder';
            end
            app.UIFigure.Visible='off';
            app.UIFigure.Visible='on';
            app.OutFolder.Value=path;
        end

        % Button pushed function: BrowseVoltageFolder
        function SearchVoltageFolder(app, event)
            path = uigetdir;
            if (path == 0)
                path = 'Browse for the Voltage Data Folder';
            end
            app.UIFigure.Visible='off';
            app.UIFigure.Visible='on';
            app.VoltageFolder.Value=path;
        end

        % Button pushed function: GenerateScriptButton
        function GenerateScript(app, event)
            scriptText='';
            scriptText=strcat(scriptText, app.UG4exe.Value);
            scriptText=strcat(scriptText," -ex ");
            scriptText=strcat(scriptText, app.LuaFile.Value);
            scriptText=strcat(scriptText," -grid ");
            scriptText=strcat(scriptText,app.GridFile.Value);
            scriptText=strcat(scriptText," -numRefs 0 -setting none ");
            scriptText=strcat(scriptText,sprintf(" -dt %0.7f",app.dt.Value));
            scriptText=strcat(scriptText,sprintf(" -endTime %0.7f",app.endtime.Value));
            scriptText=strcat(scriptText," -vtk -pstep 1.0e-5 -vmData ");
            scriptText=strcat(scriptText,app.VoltageFolder.Value);
            scriptText=strcat(scriptText," -outName ");
            scriptText=strcat(scriptText,app.OutFolder.Value);
            scriptText=strcat(scriptText," -solver GS -minDef 1e-11");
            scriptText=strrep(scriptText,"\","/");
            app.ShellScript.Value=scriptText;
        end

        % Button pushed function: Clear
        function ClearScript(app, event)
            app.ShellScript.Value='';
        end

        % Button pushed function: BrowseUG4
        function SearchUG4Folder(app, event)
            [file,path] = uigetfile;
            if (path == 0)
                app.UG4exe.Value= 'Browse for the UG4 executable';
            else
                app.UIFigure.Visible='off';
                app.UIFigure.Visible='on';
                app.UG4exe.Value=fullfile(path,file);
            end
        end

        % Button pushed function: SaveScriptButton
        function SaveScript(app, event)
            outfilename = strcat('calciumshellscript-',datestr(now,'YY-mm-DD-HH-MM-SS'));
            outfilename = strcat(outfilename,".sh");
            global filename
            filename = fullfile(app.ShellScriptFolder.Value,outfilename);
            fileID = fopen(filename,'w');
            my_str = string(app.ShellScript.Value);
            fprintf(fileID,my_str);
            fclose(fileID);
        end

        % Button pushed function: BrowseShellScriptOutFolder
        function SearchShellScriptOutput(app, event)
            path = uigetdir;
            if (path == 0)
                path = 'Where to save shell script';
            end
            app.UIFigure.Visible='off';
            app.UIFigure.Visible='on';
            app.ShellScriptFolder.Value=path;
        end

        % Button pushed function: RunScript
        function RunShellScript(app, event)
            global filename
            system(sprintf('sh %s',filename));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseLua = uibutton(app.UIFigure, 'push');
            app.BrowseLua.ButtonPushedFcn = createCallbackFcn(app, @SearchLuaScript, true);
            app.BrowseLua.Text = 'Browse';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.LuaFile = uieditfield(app.UIFigure, 'text');
            app.LuaFile.Editable = 'off';
            app.LuaFile.Value = 'Browse for the .lua script';
            app.BrowseGrid = uibutton(app.UIFigure, 'push');
            app.BrowseGrid.ButtonPushedFcn = createCallbackFcn(app, @SearchGridFile, true);
            app.BrowseGrid.Text = 'Browse';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.GridFile = uieditfield(app.UIFigure, 'text');
            app.GridFile.Editable = 'off';
            app.GridFile.Value = 'Browse for the .swc grid file';
            app.BrowseOutFolder = uibutton(app.UIFigure, 'push');
            app.BrowseOutFolder.ButtonPushedFcn = createCallbackFcn(app, @SearchOutFolder, true);
            app.BrowseOutFolder.Text = {'Browse'; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.OutFolder = uieditfield(app.UIFigure, 'text');
            app.OutFolder.Editable = 'off';
            app.OutFolder.Value = 'Use Browse to set the output folder';
            app.BrowseVoltageFolder = uibutton(app.UIFigure, 'push');
            app.BrowseVoltageFolder.ButtonPushedFcn = createCallbackFcn(app, @SearchVoltageFolder, true);
            app.BrowseVoltageFolder.Text = {'Browse'; ''};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.VoltageFolder = uieditfield(app.UIFigure, 'text');
            app.VoltageFolder.Editable = 'off';
            app.VoltageFolder.Value = 'Browse for the Voltage Data Folder';
            app.dtLabel = uilabel(app.UIFigure);
            app.dtLabel.Text = 'dt = ';
            app.dt = uieditfield(app.UIFigure, 'numeric');
            app.dt.Value = 0.002;
            app.ShellScript = uitextarea(app.UIFigure);
            app.GenerateScriptButton = uibutton(app.UIFigure, 'push');
            app.GenerateScriptButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateScript, true);
            app.GenerateScriptButton.Text = 'Generate Script';
            app.Clear = uibutton(app.UIFigure, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearScript, true);
            app.Clear.Text = 'Clear';
            app.BrowseUG4 = uibutton(app.UIFigure, 'push');
            app.BrowseUG4.ButtonPushedFcn = createCallbackFcn(app, @SearchUG4Folder, true);
            app.BrowseUG4.Text = 'Browse';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.UG4exe = uieditfield(app.UIFigure, 'text');
            app.UG4exe.Value = 'Browse for the UG4 executable';
            app.endtimeLabel = uilabel(app.UIFigure);
            app.endtimeLabel.Text = 'end time = ';
            app.endtime = uieditfield(app.UIFigure, 'numeric');
            app.endtime.Value = 0.006;
            app.SaveScriptButton = uibutton(app.UIFigure, 'push');
            app.SaveScriptButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScript, true);
            app.SaveScriptButton.Text = 'Save Script';
            app.BrowseShellScriptOutFolder = uibutton(app.UIFigure, 'push');
            app.BrowseShellScriptOutFolder.ButtonPushedFcn = createCallbackFcn(app, @SearchShellScriptOutput, true);
            app.BrowseShellScriptOutFolder.Text = 'Browse';
            app.ShellScriptFolder = uieditfield(app.UIFigure, 'text');
            app.ShellScriptFolder.Value = 'Where to save shell script';
            app.RunScript = uibutton(app.UIFigure, 'push');
            app.RunScript.ButtonPushedFcn = createCallbackFcn(app, @RunShellScript, true);
            app.RunScript.Text = 'Run Script';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scriptmaker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end